classdef LS_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExposureTimemsEditField        matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel   matlab.ui.control.Label
        CurrentPosButton_2             matlab.ui.control.Button
        CurrentPosButton               matlab.ui.control.Button
        CameraSettingsLabel            matlab.ui.control.Label
        StopPositionEditField          matlab.ui.control.NumericEditField
        StopPositionEditFieldLabel     matlab.ui.control.Label
        AcquireSettingsLabel           matlab.ui.control.Label
        StopButton                     matlab.ui.control.Button
        AdjustSmallEditField           matlab.ui.control.NumericEditField
        AdjustSmallEditFieldLabel      matlab.ui.control.Label
        RightButton_4                  matlab.ui.control.Button
        LeftButton_2                   matlab.ui.control.Button
        StartButton_2                  matlab.ui.control.Button
        AdjustLargeEditField           matlab.ui.control.NumericEditField
        AdjustLargeEditFieldLabel      matlab.ui.control.Label
        SetPositionEditField           matlab.ui.control.NumericEditField
        SetPositionEditFieldLabel      matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.NumericEditField
        CurrentPositionEditFieldLabel  matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        RightButton_3                  matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        HomeButton                     matlab.ui.control.Button
        StartPositionEditField         matlab.ui.control.NumericEditField
        StartPositionEditFieldLabel    matlab.ui.control.Label
        AcquireButton                  matlab.ui.control.Button
        NumberofImagesEditField        matlab.ui.control.NumericEditField
        NumberofImagesEditFieldLabel   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        op_states = 1;
        ConexCC;
        move_value;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConexCC = serialport("COM10",921600);
            configureTerminator(app.ConexCC,"CR/LF");
        end

        % Button pushed function: AcquireButton
        function AcquireRun(app, event)
            run_acquire(app.ConexCC, app.StartPositionEditField.Value,app.NumberofImagesEditField.Value);
        end

        % Button pushed function: HomeButton
        function Call_Home(app, event)
            app.op_states = Homing(app.op_states, app.ConexCC);
        end

        % Button pushed function: StartButton
        function move_stage(app, event)
            relative_move(app.move_value, app.ConexCC);
        end

        % Callback function: not associated with a component
        function update_move_value(app, event)
            app.move_value = app.MovemmSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NumberofImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofImagesEditFieldLabel.Text = 'Number of Images';
            app.NumberofImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcquireButton = uibutton(app.UIFigure, 'push');
            app.AcquireButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireRun, true);
            app.AcquireButton.Text = 'Acquire';
            app.StartPositionEditFieldLabel = uilabel(app.UIFigure);
            app.StartPositionEditFieldLabel.Text = 'Start Position';
            app.StartPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Call_Home, true);
            app.HomeButton.Text = {'Home'; ''};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @move_stage, true);
            app.StartButton.Text = 'Start';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.Text = 'Left';
            app.RightButton_3 = uibutton(app.UIFigure, 'push');
            app.RightButton_3.Text = 'Right';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.CurrentPositionEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPositionEditFieldLabel.Text = 'Current Position';
            app.CurrentPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetPositionEditFieldLabel = uilabel(app.UIFigure);
            app.SetPositionEditFieldLabel.Text = 'Set Position';
            app.SetPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustLargeEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustLargeEditFieldLabel.Text = 'Adjust (Large)';
            app.AdjustLargeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustLargeEditField.Value = 0.5;
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.Text = 'Start';
            app.LeftButton_2 = uibutton(app.UIFigure, 'push');
            app.LeftButton_2.Text = 'Left';
            app.RightButton_4 = uibutton(app.UIFigure, 'push');
            app.RightButton_4.Text = 'Right';
            app.AdjustSmallEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustSmallEditFieldLabel.Text = 'Adjust (Small)';
            app.AdjustSmallEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustSmallEditField.Value = 0.1;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.AcquireSettingsLabel = uilabel(app.UIFigure);
            app.AcquireSettingsLabel.Text = 'Acquire Settings';
            app.StopPositionEditFieldLabel = uilabel(app.UIFigure);
            app.StopPositionEditFieldLabel.Text = 'Stop Position';
            app.StopPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CameraSettingsLabel = uilabel(app.UIFigure);
            app.CameraSettingsLabel.Text = 'Camera Settings';
            app.CurrentPosButton = uibutton(app.UIFigure, 'push');
            app.CurrentPosButton.Text = 'Current Pos.';
            app.CurrentPosButton_2 = uibutton(app.UIFigure, 'push');
            app.CurrentPosButton_2.Text = 'Current Pos.';
            app.ExposureTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LS_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end